classdef GUIBiomecanica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        IniciarSwitchLabel  matlab.ui.control.Label
        IniciarSwitch       matlab.ui.control.Switch
        Lamp                matlab.ui.control.Lamp
        txtaMensajes        matlab.ui.control.TextArea
        axsPosicion         matlab.ui.control.UIAxes
        UITable             matlab.ui.control.Table
        axsPuntos           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearAllMemoizedCaches;
            app.Lamp.Color = [0.64,0.08,0.18];
            app.UITable.Data = zeros([8 2]);            
            app.axsPosicion.YLim = [0 380];
            app.axsPosicion.XLim = [0 512]; %514 368
            app.txtaMensajes.Value = "Iniciar Programa";
            monitor = get(0,'ScreenSize');
            interfaz = app.UIFigure.Position;
            x = (monitor(3)/2)-(interfaz(3)/2);
            y = (monitor(4)/2)-(interfaz(4)/2);
            app.UIFigure.Position = [x y interfaz(3) interfaz(4)];
        end

        % Value changed function: IniciarSwitch
        function IniciarSwitchValueChanged(app, event)
            global depthVid datos
            value = app.IniciarSwitch.Value;
            if strcmp(value,'On')
                try
                    close all;
                    clc;
                    app.Lamp.Color = [0.00,1.00,0.00];
                
                    app.txtaMensajes.Value = "Reseteando imagen";
                    imaqreset; %Resetea el objeto de imagenes
                
                    app.txtaMensajes.Value = "Creando objeto de video";
                    depthVid = videoinput('kinect', 2);
                    %colorVid = videoinput('kinect', 1);
                
                    app.txtaMensajes.Value = "Configurando Frames";
                    %confuguracion del disparador
                    depthVid.Timeout = 200.0; %se asigna un tiempo de espera para toma de frames
                    triggerconfig(depthVid,'manual');
                    depthVid.FramesPerTrigger = 1;
                    depthVid.TriggerRepeat = inf;
                    set(getselectedsource(depthVid),'EnableBodyTracking','on');
                    
                    app.txtaMensajes.Value = "Inciando Video";
                    %Inicio del video de profundidad
                    start(depthVid)
                    himg = figure; %crea un objeto figure   
                    %ejes = app.axsPosicion;
                    
                    SkeletonConnectionMap = [ [4 3];  % Neck
                          [3 21]; % Head
                          [21 2]; % Right Leg
                          [2 1];
                          [21 9];
                          [9 10];  % Hip
                          [10 11];
                          [11 12]; % Left Leg
                          [12 24];
                          [12 25];
                          [21 5];  % Spine
                          [5 6];
                          [6 7];   % Left Hand
                          [7 8];
                          [8 22];
                          [8 23];
                          [1 17];
                          [17 18];
                          [18 19];  % Right Hand
                          [19 20];
                          [1 13];
                          [13 14];
                          [14 15];
                          [15 16];
                        ];

             
                    app.txtaMensajes.Value = "Adquisición de Datos iniciada";
                    %ciclo para vizualizar los puntos en la figura
                    while ishandle(himg)
                        himg.Visible = "off";
                        trigger(depthVid);
                        [depthMap,~,depthMetaData] = getdata(depthVid);
                        %[image,imageMetaData] = getsnapshot(colorVid);
                        anyBodiesTracked = any(depthMetaData.IsBodyTracked ~= 0);
                        trackedBodies = find(depthMetaData.IsBodyTracked);
                        nBodies = length(trackedBodies);
                        colors = ['r';'g';'b';'c';'y';'m'];
                        imshow(depthMap,[0 4096],'Parent',app.axsPosicion);
                        %imaqmontage(depthMap, 'Parent', app.axsPosicion);                    
                        
                        %verifica si la suma del seguimiento del cuerpo es mayor a cero
                        if sum(depthMetaData.IsBodyTracked)>0
                            skeletonJoints = depthMetaData.DepthJointIndices(:,:,depthMetaData.IsBodyTracked);                                                       
                            plot(app.axsPosicion,skeletonJoints(:,1), skeletonJoints(:,2),'*r');                           
                            
                            hold(app.axsPuntos,"on");
                            for i = 1:24                                 
                                for body = 1:nBodies                                    
                                    X1 = [skeletonJoints(SkeletonConnectionMap(i,1),1,body); skeletonJoints(SkeletonConnectionMap(i,2),1,body)];
                                    Y1 = [skeletonJoints(SkeletonConnectionMap(i,1),2,body), skeletonJoints(SkeletonConnectionMap(i,2),2,body)];                                    
                                    plot(app.axsPuntos,X1,Y1, 'LineWidth', 2, 'LineStyle', '-' , 'Marker', '+', 'Color', colors(body), 'MarkerEdgeColor','b');                                                                                                                                                                                                                     
                                end                                
                            end
                            hold(app.axsPuntos,"off"); 
                            
                            
                            datos = [skeletonJoints(13:20,1) skeletonJoints(13:20,2)];
                            app.UITable.Data = datos;
                        else
                            imshow(depthMap,[0 4096],'Parent',app.axsPosicion);
                            %imaqmontage(depthMap, 'Parent', app.axsPosicion);
                        end
                    end
                
                catch ME
                    
                    switch ME.identifier
                        case 'imaq:kinect:createDepthInstance'
                            app.txtaMensajes.Value = 'No es posible crear un objeto de video, por favor apage el programa';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:videoinput:noDevices'
                            app.txtaMensajes.Value = 'El dispositivo Kinect no se encuentra conectado.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'MATLAB:nonExistentField'
                            app.txtaMensajes.Value = 'El seguimiento del cuerpo, no se puede realizar.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:getdata:notRunning'
                            app.txtaMensajes.Value = 'No se puede mostrar video o no hay frames disponibles.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:getdata:invalidOBJ'
                            app.txtaMensajes.Value = 'Se ha eliminado el video, programa finalizado.';
                        case 'imaq:getdata:timeout'
                            app.txtaMensajes.Value = 'El tiempo de espera esta agotado.';
                        otherwise
                            disp(ME)
                            app.Lamp.Color = [1.00,1.00,0.00];
                    end
                    
                end
                
                
            else
                app.Lamp.Color = [0.64,0.08,0.18];
                app.txtaMensajes.Value = "Programa Finalizado";
                stop(depthVid);
                flushdata(depthVid);
                delete(depthVid);
                close all;                
            end%end else
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.IniciarSwitchLabel = uilabel(app.Panel);
            app.IniciarSwitchLabel.Text = 'Iniciar';
            app.IniciarSwitch = uiswitch(app.Panel, 'slider');
            app.IniciarSwitch.ValueChangedFcn = createCallbackFcn(app, @IniciarSwitchValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.txtaMensajes = uitextarea(app.Panel);
            app.txtaMensajes.Value = {''; ''};
            app.axsPosicion = uiaxes(app.UIFigure);
            title(app.axsPosicion, 'Video')
            xlabel(app.axsPosicion, '')
            ylabel(app.axsPosicion, '')
            app.axsPosicion.LineStyleOrder = {'-'};
            app.axsPosicion.XTick = [];
            app.axsPosicion.XTickLabel = '';
            app.axsPosicion.YTick = [];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {'Cadera izquierda'; 'Rodilla izquierda'; 'Tobillo izquierdo'; 'Pie izquierdo'; 'Cadera derecha'; 'Rodilla derecha'; 'Tobillo derecho'; 'Pie derecho'};
            app.axsPuntos = uiaxes(app.UIFigure);
            title(app.axsPuntos, 'Esqueleto')
            xlabel(app.axsPuntos, '')
            ylabel(app.axsPuntos, '')
            app.axsPuntos.YDir = 'reverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIBiomecanica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end